classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoAEstimationUIFigure           matlab.ui.Figure
        change                          matlab.ui.control.StateButton
        ThefirstsourcewithMUSICisLabel  matlab.ui.control.Label
        ThesecondsourcewithMUSICisLabel  matlab.ui.control.Label
        an2                             matlab.ui.control.Label
        an1                             matlab.ui.control.Label
        MODELabel                       matlab.ui.control.Label
        MODEDropDown                    matlab.ui.control.DropDown
    end

    properties (Access = private)
        Pax; % Polar axes
        NSOURCE = 1;
        samplingTime = 0.5; % seconds
        devReader; % which was what you think
        calcRate;
        fs = 16000;
        err = 0; % if there's a problem.
    end
    
    methods (Access = private)
        
        function [P] = MUSIC(app, X)
            %MUSIC_ISM Summary of this function goes here
            %   Detailed explanation goes here
            [Frame, ~] = size(X);
            
            %% STFT
            len = 256;
            inc = 256;
            nfft = len; % The smallest 2^n \ge len, to optimize FFT
            [st_idx, ed_idx, fn] = separate(app, len, inc, Frame);
            
            
            % STFT -> 4 sensors, value after FFT,
            STFT = zeros([fn nfft 4]);
            window = hann(len);
            for i=1:fn
                for j=1:4
                    X(st_idx(i):ed_idx(i), j) = X(st_idx(i):ed_idx(i), j).*window;
                end
                STFT(i, :, :) = fft(X(st_idx(i):ed_idx(i), :), nfft);
            end
            
            % Perform MUSIC algorithm
            
            % Initialize data
            J = 4;
            dx = 2.5*10^-2;
            dy = 0;
            c = 340; % Velocity of sound
            Index = linspace(0,J-1,J);
            p = (-(J-1)/2 + Index.') * [dx dy]; % Position vector
            stride = 1; % Should not be modified!!!
            theta = -90:1:90;
            v = [sin(theta*pi/180); -cos(theta*pi/180)];
            
            P = zeros([180/stride+1 1]); % -90:stride:90
            
            fr = [40 3000]*nfft/app.fs+1; % range of frequency (to add weight)
            
            % for i=1:ceil(nfft/2)
            for i=floor(fr(1)):ceil(fr(2))
                
                % P: index -> f
                % $$\frac{(k - 1)f_s}{n}$$
                f_c = (i - 1)*app.fs/nfft;
                X_ = squeeze(STFT(:, i, :));
                
                [Frame_, ~] = size(X_);
                
                R_x = X_'*X_/Frame_;
                a_theta = exp(-1i*2*pi*f_c*(p*v)./c); % steering vector
                
                [V, D] = eig(R_x);
                eig_val = diag(D);
                [~, Idx] = sort(eig_val);
                Un = V(:, Idx(1:J-app.NSOURCE)); % noise subspace
                P_sm = diag(a_theta'*(Un*Un')*a_theta);
                P = P + P_sm;
                
            end
            
            P = 1./abs(P);
        end
        
        
        function [ st_index, ed_index, fn ] = separate(app, len, inc, Frame)
            fn = floor((Frame-len)/inc + 1);
            st_index = (0:(fn-1))*inc + 1;
            ed_index = (0:(fn-1))*inc + len;
        end
        
        
        function [source_1, source_2] = find_max(app, P)
            %FIND_MAX Summary of this function goes here
            %   Detailed explanation goes here
            theta = -90:1:90;
            [pks, locs] = findpeaks(abs(P));
            [pks, Idx] = sort(pks);
            pks = fliplr(pks);
            Idx = fliplr(Idx);
            [isize, ~] = size(Idx);
            if isize >= 2
                res = locs(Idx(1:2));
                source_1 = theta(res(1));
                source_2 = theta(res(2));
            elseif isize == 1
                res = locs(Idx(1));
                source_1 = theta(res(1));
                source_2 = 1000;
            else
                source_1 = 1000;
                source_2 = 1000;
            end
            tmp = sort([source_1 source_2]);
            source_1 = tmp(1);
            source_2 = tmp(2);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % may be this can work.
            % OK. Matlab I love you again.
            
            % polar axes.
            app.Pax = polaraxes(app.DoAEstimationUIFigure);
            app.Pax.ThetaLim = [-90,90];
            app.Pax.ThetaZeroLocation = 'top';
            app.Pax.Units = 'pixels';
            app.Pax.RLim = [0 1];
            app.Pax.ThetaMinorGrid = 'on';
            app.Pax.ThetaDir = 'clockwise';
            app.Pax.ThetaTick = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.Pax.Position = [30 10 app.DoAEstimationUIFigure.Position(3)-60 app.DoAEstimationUIFigure.Position(4)-50];
            
            
            % audio deviece can be set here.
            try
                app.devReader = audioDeviceReader( ...
                    'Driver', 'DirectSound', ...
                    'SamplesPerFrame', app.fs*app.samplingTime, ...
                    'SampleRate', app.fs, ...
                    'NumChannels', 4, ...
                    'BitDepth', '16-bit integer', ...
                    'Device', '麦克风 (USB YDB01 Audio Effect)', ...
                    'ChannelMappingSource', 'Property', ...
                    'ChannelMapping', [2 1 4 3] ...
                    );
            catch ME
                if(strcmp(ME.identifier,"MATLAB:system:StringSet:InvalidValue"))
                    app.change.Text = "Retry";
                    app.err = 1;
                    errordlg("Please connect microphone.","No Micophone");
                    
                end
            end
        end

        % Value changed function: change
        function changeValueChanged(app, event)
            % ensure the mode.
            switch app.MODEDropDown.Value
                case "Real-Time-0.3s"
                    app.samplingTime = 0.3;
                case "Real-Time-0.5s"
                    app.samplingTime = 0.5;
                case "Broadband-3s"
                    app.samplingTime = 3;
            end
            app.calcRate = 1/app.samplingTime;
            
            
            if(app.change.Value == 0)
                
            end
            % run prepare part
            if(app.change.Value == 1)
                if app.samplingTime == 3
                    app.change.Text = "Wait...";
                    drawnow;
                end
                try
                    app.devReader = audioDeviceReader( ...
                        'Driver', 'DirectSound', ...
                        'SamplesPerFrame', app.fs*(app.samplingTime), ...
                        'SampleRate', app.fs, ...
                        'NumChannels', 4, ...
                        'BitDepth', '16-bit integer', ...
                        'Device', '麦克风 (USB YDB01 Audio Effect)', ...
                        'ChannelMappingSource', 'Property', ...
                        'ChannelMapping', [2 1 4 3] ...
                        );
                catch ME
                    if(strcmp(ME.identifier,"MATLAB:system:StringSet:InvalidValue"))
                        app.change.Text = "Retry";
                        app.err = 1;
                        app.change.Value = 0;
                        errordlg("Please connect microphone.","No Micophone");
                        
                        return;
                    end
                end
                
                % ensure there's not problems.
                % OK now I think it's OK.
                try
                    setup(app.devReader); % where to setup and release is still a problem.
                catch ME
                    if(strcmp(ME.identifier,"MATLAB:UndefinedFunction"))
                        errordlg("Please connect microphone.","No Micophone");
                        app.change.Text = "Retry";
                        app.err = 1;
                        app.change.Value = 0;
                        
                        return;
                    end
                end
                
                % do necessary work and start the timer.
                if app.samplingTime ~= 3
                    app.change.Text = 'Stop';
                end
                disp(['Calculation rate is: ', num2str(app.calcRate)]);
                disp("Start collecting...");
            end
            
            
            while(app.change.Value == 1)
                % run work part
                X = hilbert(app.devReader());
                [P] = MUSIC(app, X);
                
                % plot the graph.
                step = 180/(length(P)-1);
                angle = -90:step:90;
                theta_radians = deg2rad(angle);
                app.Pax.Colormap = [1 0 1; 0 0 1; 1 1 0];
                polarplot(app.Pax, theta_radians, abs(P));
                app.Pax.ThetaLim = [-90,90];
                app.Pax.ThetaZeroLocation = 'top';
                app.Pax.Units = 'pixels';
                % app.Pax.RTick = [0 0.08 0.16 0.24 0.32 0.4];
                app.Pax.RLim = [0 0.4];
                app.Pax.ThetaMinorGrid = 'on';
                app.Pax.LineWidth = 0.5;
                app.Pax.ThetaDir = 'clockwise';
                app.Pax.ThetaTick = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
                app.Pax.Position = [30 10 app.DoAEstimationUIFigure.Position(3)-60 app.DoAEstimationUIFigure.Position(4)-50];
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                drawnow; % woc!!!!!  nb!!!!!! I announce that this command is my father!!!!!!!
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Find the local maximum;
                [source_1, source_2] = find_max(app, P);
                
                disp(['The first source with MUSIC is: ',num2str(source_1),' deg']);
                disp(['The second source with MUSIC is: ',num2str(source_2),' deg']);
                app.an1.Text = num2str(source_1);
                app.an2.Text = num2str(source_2);
                
                if app.samplingTime == 3
                    app.change.Value = 0;
                    app.change.Text = "Restart";
                    release(app.devReader);
                    return;
                end
                drawnow;
            end
            release(app.devReader);
            app.change.Text = 'Continue';
        end

        % Close request function: DoAEstimationUIFigure
        function DoAEstimationUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoAEstimationUIFigure = uifigure('Visible', 'off');
            app.DoAEstimationUIFigure.Name = 'DoA-Estimation';
            app.DoAEstimationUIFigure.CloseRequestFcn = createCallbackFcn(app, @DoAEstimationUIFigureCloseRequest, true);
            app.change = uibutton(app.DoAEstimationUIFigure, 'state');
            app.change.ValueChangedFcn = createCallbackFcn(app, @changeValueChanged, true);
            app.change.Text = 'Start';
            app.ThefirstsourcewithMUSICisLabel = uilabel(app.DoAEstimationUIFigure);
            app.ThefirstsourcewithMUSICisLabel.Text = 'The first source with MUSIC is:';
            app.ThesecondsourcewithMUSICisLabel = uilabel(app.DoAEstimationUIFigure);
            app.ThesecondsourcewithMUSICisLabel.Text = 'The second source with MUSIC is:';
            app.an2 = uilabel(app.DoAEstimationUIFigure);
            app.an2.Text = 'UKN';
            app.an1 = uilabel(app.DoAEstimationUIFigure);
            app.an1.Text = 'UKN';
            app.MODELabel = uilabel(app.DoAEstimationUIFigure);
            app.MODELabel.Text = {'MODE:'; ''};
            app.MODEDropDown = uidropdown(app.DoAEstimationUIFigure);
            app.MODEDropDown.Items = {'Real-Time-0.3s', 'Real-Time-0.5s', 'Broadband-3s'};
            app.MODEDropDown.Value = 'Real-Time-0.3s';
            % Show the figure after all components are created
            app.DoAEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoAEstimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoAEstimationUIFigure)
        end
    end
end